classdef SphericalDensityhTool_v00 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SphericalDensityToolUIFigure   matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        PushTool_OpenFile              matlab.ui.container.toolbar.PushTool
        PushTool_SaveState             matlab.ui.container.toolbar.PushTool
        PushTool_LoadState             matlab.ui.container.toolbar.PushTool
        PushTool_About                 matlab.ui.container.toolbar.PushTool
        PlotsettingsPanel              matlab.ui.container.Panel
        PeaksCheckBox                  matlab.ui.control.CheckBox
        LabelsCheckBox                 matlab.ui.control.CheckBox
        FillcontourplotCheckBox        matlab.ui.control.CheckBox
        TransparencyEditField          matlab.ui.control.NumericEditField
        TransparencyEditFieldLabel     matlab.ui.control.Label
        inversecolormapCheckBox        matlab.ui.control.CheckBox
        ColormapDropDown               matlab.ui.control.DropDown
        ColormapDropDownLabel          matlab.ui.control.Label
        ConsoleListBoxLabel            matlab.ui.control.Label
        DensityandmaximasettingsPanel  matlab.ui.container.Panel
        MaxangleEditField              matlab.ui.control.NumericEditField
        MaxangleEditFieldLabel         matlab.ui.control.Label
        NofppalEditField               matlab.ui.control.NumericEditField
        NofppalEditFieldLabel          matlab.ui.control.Label
        MindensityEditField            matlab.ui.control.NumericEditField
        MindensityLabel                matlab.ui.control.Label
        BandwidthEditField             matlab.ui.control.NumericEditField
        BandwidthLabel                 matlab.ui.control.Label
        NsecEditField                  matlab.ui.control.NumericEditField
        NsecEditFieldLabel             matlab.ui.control.Label
        ConsoleListBox                 matlab.ui.control.ListBox
        ControlPanel                   matlab.ui.container.Panel
        Plot_Normals                   matlab.ui.control.Button
        Compute_Density                matlab.ui.control.Button
        Find_Maxima                    matlab.ui.control.Button
        Plot_Contours                  matlab.ui.control.Button
        EditPanel                      matlab.ui.container.Panel
        Button_bajatop                 matlab.ui.control.Button
        Button_bajafila                matlab.ui.control.Button
        Button_subefila                matlab.ui.control.Button
        Button_subetop                 matlab.ui.control.Button
        Button_deleterow               matlab.ui.control.Button
        Button_addrow                  matlab.ui.control.Button
        UITable_Resultados             matlab.ui.control.Table
    end

    
    properties (Access = private)
        lastFilePath = ''; % Variable para almacenar la ruta del último archivo cargado
        data % String en el que guardo los datos que tengo
    end
    
    methods (Access = private)
        
        function [relative_maxima, top_N_density, top_N_positions, top_N_normals ] = f_busca_max_relativo_densidad(app,x_grid, y_grid, z_grid, density, densidadminima, resolucion, angle_limit, N)
            % Inputs:
            % - x_grid, y_grid, z_grid: arreglos de las coordenadas de los puntos, de dimensión nxn
            % - density: arreglo con los valores de densidad, tb nxn
            % - densidadminima: % de la máxima densidad para aceptar un máximo local
            % - resolucion => n=2^k-1; % Número de saltos para definir la precisión de la densidad, 
            % - angle_limit = 20; % Límite de ángulo en grados para la selección de máximos relativos
            % - N = 10; % Número de puntos de mayor densidad
            % Output:
            % relative_maxima,
            % top_N_density,
            % top_N_positions,
            % top_N_normals
            % La densidad mínima está en tanto por uno, por lo que calculo
            % el valor mínimo para ser considerado
            densidadminima=densidadminima*max(max(density));
            grid_points = [x_grid(:), y_grid(:), z_grid(:)];
            relative_maxima = [];
            for i = 1:resolucion
                for j = 1:resolucion
                    if i == 1 || i == resolucion || j == 1 || j == resolucion
                        current_point = [x_grid(i, j), y_grid(i, j), z_grid(i, j)];
                        [indices, ~] = knnsearch(grid_points, current_point, 'K', 9);
                        neighbors = density(indices(2:end));
                        if density(i, j) > densidadminima && all(density(i, j) >= neighbors)
                            relative_maxima = [relative_maxima; x_grid(i, j), y_grid(i, j), z_grid(i, j), density(i, j)];
                        end
                    else
                        neighbors = [density(i-1, j-1), density(i-1, j), density(i-1, j+1), ...
                            density(i, j-1), density(i, j+1), ...
                            density(i+1, j-1), density(i+1, j), density(i+1, j+1)];
                        if density(i, j) > densidadminima && all(density(i, j) > neighbors)
                            relative_maxima = [relative_maxima; x_grid(i, j), y_grid(i, j), z_grid(i, j), density(i, j)];
                        end
                    end
                end
            end
            % Ordenar los máximos relativos por densidad de mayor a menor
            relative_maxima = sortrows(relative_maxima, -4);
            cos_angle_limit = cos(deg2rad(angle_limit));
            top_N_density = [];
            top_N_positions = [];
            top_N_normals = [];
            for i = 1:size(relative_maxima, 1)
                if length(top_N_density) >= N
                    break;
                end
                normal = relative_maxima(i, 1:3);
                if isempty(top_N_normals)
                    if normal(3)<0 % Si el vector normal está en el hemisferio inferior, lo invierto para que apunte al hemisferio superior
                        normal=-normal;
                    end
                    top_N_density = [top_N_density; relative_maxima(i, 4)];
                    top_N_positions = [top_N_positions; normal];
                    top_N_normals = [top_N_normals; normal];
                else
                    valid = true;
                    for j = 1:size(top_N_normals, 1)
                        projection = abs(dot(normal, top_N_normals(j, :)) / (norm(normal) * norm(top_N_normals(j, :))));
                        if projection > cos_angle_limit
                            valid = false;
                            break;
                        end
                    end
                    if valid
                        top_N_density = [top_N_density; relative_maxima(i, 4)];
                        if normal(3)<0 % Si el vector normal está en el hemisferio inferior, lo invierto para que apunte al hemisferio superior
                            normal=-normal;
                        end
                        top_N_positions = [top_N_positions; normal];
                        top_N_normals = [top_N_normals; normal];
                    end
                end
            end

        end
        
        function bw = optimal_bandwidth(app,data)
            % Función para calcular el ancho de banda óptimo
            n = size(data, 1);
            d = size(data, 2);
            bw = (4/(d+2))^(1/(d+4)) * n^(-1/(d+4));
        end
        
        function [ dipdir, dip ] = f_vnorm2clar_v02( app,Normales )   
            % [ dipdir, dip ] = f_vnorm2clar( N )
            % dirección de buzamiento y buzamiento en grados
            % N es el vector normal del plano
            % Función que convierte un vector normal de un polo a notación de Clar
            % N=[nx ny nz] --> dip dir y dip en grados
            % N puede ser un conjunto de vectores normales, puestos en fila. Dimensión % nx3
            % V02: intento optmizar eliminando el bucle for
            % Nota: no necesita la proyección, es independiente
            %    Copyright (C) {2024}  {Adrián Riquelme Guill, adririquelme@gmail.com}
            %
            %    This program is free software; you can redistribute it and/or modify
            %    it under the terms of the GNU General Public License as published by
            %    the Free Software Foundation; either version 2 of the License, or
            %    any later version.
            %
            %    This program is distributed in the hope that it will be useful,
            %    but WITHOUT ANY WARRANTY; without even the implied warranty of
            %    MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
            %    GNU General Public License for more details.
            %
            %    You should have received a copy of the GNU General Public License along
            %    with this program; if not, write to the Free Software Foundation, Inc.,
            %    51 Franklin Street, Fifth Floor, Boston, MA 02110-1301 USA.
            %    Discontinuity Set Extractor, Copyright (C) 2015 Adrián Riquelme Guill
            %    Discontinuity Set Extractor comes with ABSOLUTELY NO WARRANTY.
            %    This is free software, and you are welcome to redistribute it
            %    under certain conditions.

            % En primer lugar, hago que todas las normales vayan al hemisferio superior
            I=find(Normales(:,3)<0);
            Normales(I,:)=Normales(I,:)*(-1);

            [n,~]=size(Normales);
            dipdir=zeros(n,1);
            dip=dipdir;

            % Normalizo. No hace falta.
            % Modulos=(Normales.*Normales);
            % Modulos=sum(Modulos,2).^(0.5); % sumo la dimensión dos, que son las filas
            % Normales=Normales./Modulos;
            % Busco los vectores con normal vertical, plano horizontal
            I=find(Normales(:,3)==1);
            dipdir(I)=0;
            dip(I)=0;

            % Ahora vamos a por el resto
            % Primero
            I=find(Normales(:,3)<1);
            % Omega es el ángulo que forma el vector respecto a la horizontal, positivo
            % en horario. Hay que convertirlo a alpha, que es el ángulo respecto al
            % norte
            omega=atan2(Normales(I,2),Normales(I,1)); % P = atan2(Y,X)
            omega(omega<0)=omega(omega<0)+2*pi; % los negativos los pongo en positivo

            alpha=pi/2-omega;
            alpha(alpha<0)=2*pi+alpha(alpha<0);

            dipdir(I)=alpha;

            % A los planos verticales les pongo dip de pi/2
            dip(Normales(:,3)==0)=pi/2;
            % Los que no son horizontales, calculo
            I=find(Normales(:,3)~=0);
            dip(I)=atan((Normales(I,1).^2+Normales(I,2).^2).^(0.5)./Normales(I,3));
            % Ya he terminado, lo paso a grados
            dipdir=dipdir*180/pi;
            dip=dip*180/pi;
        end
        
        function [h] = f_dibuja_densidad(app,x_grid, y_grid, z_grid, density, alpha, offset, transparency)
            % Dibujar la densidad en la superficie de la esfera
            % Inputs:
            % - x_grid, y_grid, z_grid, density
            % - alpha = 15; % Hasta donde llegan las líneas desde los polos
            % - offset = 0.1; % Separación desde la superficie para las etiquetas
            % Ouput:
            % - h: handle de la figura que he creado
            h=figure;
            % Ahora dibujo la densidad
            s = surf(x_grid, y_grid, z_grid, density, 'EdgeColor', 'none');
            s.FaceAlpha = transparency; %0.9 queda chulo
            s.FaceColor = 'interp'; % FaceColor — Color de cara 'flat' (predeterminado) | 'interp' | 'none' | 'texturemap' | Triplete RGB | código de color hexadecimal | 'r' | 'g' | 'b' | ...
            s.EdgeColor = 'interp'; % EdgeColor — Color de línea de borde [0 0 0] (predeterminado) | 'none' | 'flat' | 'interp' | Triplete RGB | código de color hexadecimal | 'r' | 'g' | 'b' | ...
            s.LineStyle='-'; % LineStyle — Estilo de línea "-" (predeterminado) | "--" | ":" | "-." | "none"
            cmap=colormap(jet);
            colorbar;
            axis equal;
            xlabel('X');
            ylabel('Y');
            zlabel('Z');
            title('Density of normals');
            % Ajusto los límites a la densidad
            mind=min(min(density));
            maxd=max(max(density));
            g=gca;
            g.CLim=[mind maxd];
            hold on
            % Añadir la estructura alámbrica de la esfera en azul
            [theta_wire, phi_wire] = meshgrid(linspace(0, 2*pi, 24), linspace(0, pi-alpha*pi/180, 12));
            x_wire = sin(phi_wire) .* cos(theta_wire);
            y_wire = sin(phi_wire) .* sin(theta_wire);
            z_wire = cos(phi_wire);
            m = mesh(x_wire, y_wire, z_wire, 'EdgeColor', 'b', 'FaceAlpha', 0);
            m.LineStyle = '-';
            % Añadir el ecuador (línea roja)
            theta_eq = linspace(0, 2*pi, 100);
            x_eq = cos(theta_eq);
            y_eq = sin(theta_eq);
            z_eq = zeros(size(theta_eq));
            plot3(x_eq, y_eq, z_eq, 'r--', 'LineWidth', 2);
            % Añadir los meridianos principales (líneas rojas discontinuas)
            phi_mer = linspace(0, 2*pi, 100);
            x_mer1 = sin(phi_mer);
            y_mer1 = zeros(size(phi_mer));
            z_mer1 = cos(phi_mer);
            plot3(x_mer1, y_mer1, z_mer1, 'r--', 'LineWidth', 2);
            x_mer2 = sin(phi_mer) .* cos(pi/2);
            y_mer2 = sin(phi_mer) .* sin(pi/2);
            z_mer2 = cos(phi_mer);
            plot3(x_mer2, y_mer2, z_mer2, 'r--', 'LineWidth', 2);
            % Añadir etiquetas en las coordenadas especificadas con recuadro blanco y marco negro
            text(0, 1 + offset, 0, 'N', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'BackgroundColor', 'w', 'EdgeColor', 'k');
            text(0, -1 - offset, 0, 'S', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'BackgroundColor', 'w', 'EdgeColor', 'k');
            text(1 + offset, 0, 0, 'E', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'BackgroundColor', 'w', 'EdgeColor', 'k');
            text(-1 - offset, 0, 0, 'W', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'BackgroundColor', 'w', 'EdgeColor', 'k');
            hold off
            % guardo la información en el log
            a = app.ConsoleListBox.Items;
            DateString=char(string(datetime));
            S1 = [DateString, ' - I have plotted the density of normal vectors on the sphere, along with the detected local maxima.'];
            S=[a S1];
            app.ConsoleListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.ConsoleListBox, 'bottom')
            app.data.log = S;
        end
        
        function mapadecolores = f_colormaps(app,colormap)
            switch colormap
                case 'batlowW'
                    % Fuente: https://doi.org/10.1038/s41467-020-19160-7
                    % Mapa de colores que se percibe uniforme, más que
                    % otros
                    % El batlow White termina en blanco, y es bueno porque
                    % lo puedo invertir y valores bajos de densidad quedan
                    % en blanco y al subir van cambiando a tonos oscuros
                    mapadecolores=[0.00463682846441711	0.0983434301456640	0.349832870873782
                        0.00858019345199138	0.104559284113183	0.350923311029386
                        0.0125649246059743	0.110824915117973	0.351981384068802
                        0.0161709067821996	0.116931921783254	0.353057436167705
                        0.0196226364495734	0.122981558720696	0.354105812973404
                        0.0229160779638364	0.129014151746803	0.355167818384576
                        0.0260557309371420	0.135014341140128	0.356195466992292
                        0.0290464542219426	0.140931358616234	0.357225041860249
                        0.0318907528044727	0.146752557477930	0.358228849577164
                        0.0346955148643826	0.152561996930851	0.359227707842503
                        0.0373668469671282	0.158356651768641	0.360218627817055
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                        1	0.995792119441494	0.995629773779152];
                otherwise
                    % me has dado un mapa que no tengo
            end
            
        end
        
        function [h] = f_dibuja_normales(app,x_grid, y_grid, z_grid, datos, alpha, offset, transparency, resolucion)
            % Dibujar la densidad en la superficie de la esfera
            % Inputs:
            % - x_grid, y_grid, z_grid, density
            % - alpha = 15; % Hasta donde llegan las líneas desde los polos
            % - offset = 0.1; % Separación desde la superficie para las etiquetas
            % Ouput:
            % - h: handle de la figura que he creado
            h=figure;
            % Dibujo una esfera de radio 1
            sphere(24,FaceColor="white",FaceAlpha=transparency,EdgeAlpha=transparency,EdgeColor=[1 1 1]);
            axis equal;
            hold on
            % Ahora dibujo los puntos
            tamanyo=1;
            color=[0.4660 0.6740 0.1880]; % Es un verde que queda bien
            puntos=scatter3(datos(:,1),datos(:,2),datos(:,3),tamanyo,color);
            % puntos.MarkerFaceColor=[0.4660 0.6740 0.1880];
            puntos.MarkerEdgeColor=[0 0 0];
            puntos.Marker=".";
            % puntos.SizeData=1;
            puntos.MarkerFaceColor="auto";
            puntos.MarkerEdgeColor="flat";
            
            xlabel('X');
            ylabel('Y');
            zlabel('Z');
            title('Normals');
            % Ajusto los límites a la densidad
            mind=min(min(datos));
            maxd=max(max(datos));
            g=gca;
            g.CLim=[mind maxd];

            hold on
            anchodelinea=0.3;
            % Añadir la estructura alámbrica de la esfera en azul
            [theta_wire, phi_wire] = meshgrid(linspace(0, 2*pi, 24), linspace(0, pi-alpha*pi/180, 12));
            factor_escalado=1.01; % Para que la que quede un poco por encima de los puntos, que los taparía
            x_wire = sin(phi_wire) .* cos(theta_wire)*factor_escalado;
            y_wire = sin(phi_wire) .* sin(theta_wire)*factor_escalado;
            z_wire = cos(phi_wire)*factor_escalado;
            m = mesh(x_wire, y_wire, z_wire, 'EdgeColor', 'b', 'FaceAlpha', 0);
            m.LineStyle = '-';
            m.LineWidth=anchodelinea;
            % Añadir el ecuador (línea roja)
            theta_eq = linspace(0, 2*pi, 100);
            x_eq = cos(theta_eq);
            y_eq = sin(theta_eq);
            z_eq = zeros(size(theta_eq));
            plot3(x_eq, y_eq, z_eq, 'r--', 'LineWidth', anchodelinea);
            % Añadir los meridianos principales (líneas rojas discontinuas)
            phi_mer = linspace(0, 2*pi, 100);
            x_mer1 = sin(phi_mer)*factor_escalado;
            y_mer1 = zeros(size(phi_mer))*factor_escalado;
            z_mer1 = cos(phi_mer)*factor_escalado;
            plot3(x_mer1, y_mer1, z_mer1, 'r--', 'LineWidth', anchodelinea);
            x_mer2 = sin(phi_mer) .* cos(pi/2)*factor_escalado;
            y_mer2 = sin(phi_mer) .* sin(pi/2)*factor_escalado;
            z_mer2 = cos(phi_mer)*factor_escalado;
            plot3(x_mer2, y_mer2, z_mer2, 'r--', 'LineWidth', anchodelinea);
            axis equal;

            % Añadir etiquetas en las coordenadas especificadas con recuadro blanco y marco negro
            text(0, 1 + offset, 0, 'N', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'BackgroundColor', 'w', 'EdgeColor', 'k');
            text(0, -1 - offset, 0, 'S', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'BackgroundColor', 'w', 'EdgeColor', 'k');
            text(1 + offset, 0, 0, 'E', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'BackgroundColor', 'w', 'EdgeColor', 'k');
            text(-1 - offset, 0, 0, 'W', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'BackgroundColor', 'w', 'EdgeColor', 'k');
            hold off
            % guardo la información en el log
            a = app.ConsoleListBox.Items;
            DateString=char(string(datetime));
            S1 = [DateString, ' - I have plotted the normal vectors on the sphere as intersection points.'];
            S=[a S1];
            app.ConsoleListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.ConsoleListBox, 'bottom')
            app.data.log = S;
            
        end
        
        function globe = f_dibuja_contorno(~,theta_grid, phi_grid, density,  alpha, offset, transparency)
                % ------------------------ DATOS ------------------------
                % Parámetro de resolución de textura (ajusta nitidez en la esfera)
                capture_resolution_factor = 5; % 1 = original, >1 = más resolución
                % Parámetro de resolución de la textura proyectada en la esfera
                res_factor = 1; % 1 = original, <1 reduce, >1 aumenta
                % Control de iluminación
                use_lighting = false;    % Cambia a false para desactivar la luz
                light_intensity = 0.5;  % Ajusta la intensidad de la luz (0 = sin luz, 1 = máxima)
                light_distance = 10;    % Distancia del foco de luz (ajustable)
                % -------------------- CAPTURA DE IMAGEN --------------------
                % Crear figura invisible para captura
                fig = figure('Visible', 'off', 'Position', [100 100 600 600] * capture_resolution_factor);
                ax = axes(fig);
                contour3(ax, phi_grid, theta_grid, density, 50, 'LineWidth', 1.2);
                axis off;
                axis equal;
                view([0 90]); % Vista cenital
                camproj('orthographic');
                set(ax, 'Position', [0 0 1 1]);
                % Ajustar las propiedades de la figura para mayor resolución
                set(fig, 'PaperPositionMode', 'auto');
                set(fig, 'PaperUnits', 'inches', 'PaperPosition', [0 0 6 6] * capture_resolution_factor);
                % Capturar la imagen con mayor resolución
                frame = getframe(ax);
                img = frame.cdata; % Guardar imagen en memoria
                % Hay que invertirla para que los polos estén en su sitio
                img = flipud(img);
                close(fig);
                % Paso 2: Redimensionar la imagen según res_factor
                img_resized = imresize(img, res_factor); % Ajustar resolución final
                % ------------------- PROYECCIÓN EN LA ESFERA -------------------
                % Crear la esfera con la nueva resolución (intercambiando phi y theta)
                [lat, lon] = meshgrid(linspace(-90, 90, size(img_resized,1)), linspace(-180, 180, size(img_resized,2)));
                [x, y, z] = sph2cart(deg2rad(lon), deg2rad(lat), 1); % Intercambiamos phi y theta
                % Proyectar la textura sobre la esfera con vista ortográfica
                h=figure;
                hold on;
                globe = surface(x, y, z, 'FaceColor', 'texturemap', 'CData', img_resized, 'EdgeColor', 'none','FaceAlpha', transparency);
                % -------------------- CONFIGURACIÓN DE VISTA --------------------
                view(3); % Vista isométrica mostrando los 3 ejes
                camproj('orthographic'); % Proyección ortográfica
                axis equal;
                grid on; % Mostrar rejilla
                xlabel('X'); ylabel('Y'); zlabel('Z'); % Etiquetas de ejes
                % -------------------- ILUMINACIÓN CONTROLADA --------------------
                if use_lighting
                    light_position = [light_distance light_distance light_distance]; % Control de distancia
                    hLight = light('Position', light_position, 'Style', 'infinite');
                    set(hLight, 'Color', [light_intensity light_intensity light_intensity]); % Ajustar intensidad
                    lighting gouraud; % Suavizar sombras
                end
                % Añadir la estructura alámbrica de la esfera en azul
                [theta_wire, phi_wire] = meshgrid(linspace(0, 2*pi, 24), linspace(0, pi-alpha*pi/180, 12));
                x_wire = sin(phi_wire) .* cos(theta_wire);
                y_wire = sin(phi_wire) .* sin(theta_wire);
                z_wire = cos(phi_wire);
                m = mesh(x_wire, y_wire, z_wire, 'EdgeColor', 'b', 'FaceAlpha', 0);
                m.LineStyle = '-';
                % Añadir el ecuador (línea roja)
                theta_eq = linspace(0, 2*pi, 100);
                x_eq = cos(theta_eq);
                y_eq = sin(theta_eq);
                z_eq = zeros(size(theta_eq));
                plot3(x_eq, y_eq, z_eq, 'r--', 'LineWidth', 2);
                % Añadir los meridianos principales (líneas rojas discontinuas)
                phi_mer = linspace(0, 2*pi, 100);
                x_mer1 = sin(phi_mer);
                y_mer1 = zeros(size(phi_mer));
                z_mer1 = cos(phi_mer);
                plot3(x_mer1, y_mer1, z_mer1, 'r--', 'LineWidth', 2);
                x_mer2 = sin(phi_mer) .* cos(pi/2);
                y_mer2 = sin(phi_mer) .* sin(pi/2);
                z_mer2 = cos(phi_mer);
                plot3(x_mer2, y_mer2, z_mer2, 'r--', 'LineWidth', 2);
                % Añadir etiquetas en las coordenadas especificadas con recuadro blanco y marco negro
                text(0, 1 + offset, 0, 'N', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'BackgroundColor', 'w', 'EdgeColor', 'k');
                text(0, -1 - offset, 0, 'S', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'BackgroundColor', 'w', 'EdgeColor', 'k');
                text(1 + offset, 0, 0, 'E', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'BackgroundColor', 'w', 'EdgeColor', 'k');
                text(-1 - offset, 0, 0, 'W', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'BackgroundColor', 'w', 'EdgeColor', 'k');
                hold off
                % Pues ya estaría la movida terminada
        end
        
        function results = f_openfile(app)
            % Si es la primera vez, abrir la ventana por defecto
            if isempty(app.lastFilePath)
                [file, path] = uigetfile('*.ply', 'Select Point Cloud File');
            else
                [file, path] = uigetfile('*.ply', 'Select Point Cloud File', app.lastFilePath);
            end

            % Si el usuario no cancela la selección
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                % Actualizar la ruta del último archivo cargado
                app.lastFilePath = fullfile(path, file);
                app.data.file=file;

                % Leer el archivo .ply
                ptCloud = pcread(app.lastFilePath);

                % Comprobar si el archivo tiene normales
                if ~isempty(ptCloud.Normal)
                    app.data.normales_originales = ptCloud.Normal;                 
                    normales_inversas = ptCloud.Normal * (-1);
                    [n, ~] = size(ptCloud.Normal);
                    datos = zeros(2*n, 3);
                    datos(1:n, :) = ptCloud.Normal;
                    datos(n+1:2*n, :) = normales_inversas;
                    % preparo las coordeandas con los datos
                    x = datos(:,1)';
                    y = datos(:,2)';
                    z = datos(:,3)';
                    app.data.datos=datos;
                    app.data.x=x;
                    app.data.y=y;
                    app.data.z=z;
                    % Activo los settings y el botón
                    app.MaxangleEditField.Enable="on";
                    app.NofppalEditField.Enable="on";
                    app.MindensityEditField.Enable="on";
                    app.BandwidthEditField.Enable="on";
                    app.NsecEditField.Enable="on";
                    app.Compute_Density.Enable="on";
                    app.ColormapDropDown.Enable="off";
                    app.inversecolormapCheckBox.Enable="off";
                    app.TransparencyEditField.Enable="off";
                    app.Find_Maxima.Enable="off";
                    app.LabelsCheckBox.Enable="off";
                    app.LabelsCheckBox.Enable="off";
                    app.Plot_Contours.Enable="off";
                    app.FillcontourplotCheckBox.Enable="off";
                    app.Plot_Normals.Enable="on";
                    % La edición de polos la desactivo
                    % app.EditPanel.Enable="off";
                    app.Button_bajatop.Enable="off";
                    app.Button_bajafila.Enable="off";
                    app.Button_subefila.Enable="off";
                    app.Button_subetop.Enable="off";
                    app.Button_deleterow.Enable="off";
                    app.Button_addrow.Enable="off";
                    app.UITable_Resultados.Enable="off";
                    app.UITable_Resultados.Data =[]; % Borro los resultados previos
                    % disp('Point cloud normals loaded successfully.');
                    % guardo la información en el log
                    a = app.ConsoleListBox.Items;
                    DateString=char(string(datetime));
                    S1 = [DateString, ' - Point cloud loaded successfully. File: ',file,' number of points: ',num2str(length(ptCloud.Location))];
                    S=[a S1];
                    app.ConsoleListBox.Items=S;
                    % fijo la vista del listbox en la última entrada
                    scroll(app.ConsoleListBox, 'bottom')
                    app.data.log = S;
                else
                    uialert(app.SphericalDensityToolUIFigure, 'The selected .ply file does not contain normals.', 'File Error');
                     % guardo la información en el log
                    a = app.ConsoleListBox.Items;
                    DateString=char(string(datetime));
                    S1 = [DateString, ' - The point cloud has not normals, so it was not loaded.'];
                    S=[a S1];
                    app.ConsoleListBox.Items=S;
                    % fijo la vista del listbox en la última entrada
                    scroll(app.ConsoleListBox, 'bottom')
                    app.data.log = S;
                end

            end
               % Traer la GUI al frente
    figure(app.SphericalDensityToolUIFigure); 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Comprobar si el Computer Vision Toolbox está instalado
            addons = matlab.addons.installedAddons();
            % Configuro la tabla
            app.UITable_Resultados.RowName = 'numbered';
            if ~any(strcmp(addons.Name, 'Computer Vision Toolbox'))
                % Mostrar mensaje de advertencia
                uialert(app.SphericalDensityToolUIFigure, 'Computer Vision Toolbox is not installed. The app will close.', 'Toolbox Missing');
                % Pausar para que el usuario pueda leer el mensaje
                pause(3);
                % Cerrar la app
                delete(app);
            end
        end

        % Button pushed function: Compute_Density
        function Compute_DensityPushed(app, event)
            tic;
            % Aviso de que empiezo a calcular la densitdad
            a = app.ConsoleListBox.Items;
            DateString=char(string(datetime));
            S1 = [DateString, ' - Calculating density via KDE. Nsec: ',num2str(app.NsecEditField.Value),', selected bandwidth: ',num2str(app.BandwidthEditField.Value),'. Please, wait.'];
            S=[a S1];
            app.ConsoleListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.ConsoleListBox, 'bottom')
            app.data.log = S;
            % resolucion=2^app.NsecEditField.Value-1;
            resolucion=2^app.NsecEditField.Value; % Nsec es un número entero, norlamente de 6 a 8
            switch app.BandwidthEditField.Value
                case 0
                    bandwidth=optimal_bandwidth(app, app.data.datos);
                otherwise
                    bandwidth=sind(app.BandwidthEditField.Value);
            end
            % densidadminima=app.MindensityEditField.Value;
            % N=app.NofppalEditField.Value;
            % angle_limit=app.MaxangleEditField.Value;
            % offset = 0.1; % Separación desde la superficie para las etiquetas
            % alpha = 15; % Hasta donde llegan las líneas desde los polos
            % Crear una malla de puntos en la superficie de la esfera
            [theta_grid, phi_grid] = meshgrid(linspace(0, 2*pi, resolucion), linspace(0, pi, resolucion));
            x_grid = sin(phi_grid) .* cos(theta_grid);
            y_grid = sin(phi_grid) .* sin(theta_grid);
            z_grid = cos(phi_grid);
            % Aplicar KDE para estimar la densidad con kernel gaussiano y ancho de banda óptimo
            x=app.data.x;
            y=app.data.y;
            z=app.data.z;
            datos = [x', y', z'];
            grid_points = [x_grid(:), y_grid(:), z_grid(:)];
            app.data.grid_points=grid_points;
            % bandwidth = optimal_bandwidth(data);
            densidad_calculada = mvksdensity(datos, grid_points, 'Bandwidth', bandwidth, 'Kernel', 'normal');
            density=densidad_calculada/max(densidad_calculada); % normalizo la densidad para que el valor máximo sea de 1
            density = reshape(density, size(x_grid));
            % Guardo las variables
            app.data.theta_grid=theta_grid;
            app.data.phi_grid=phi_grid;
            app.data.x_grid=x_grid;
            app.data.y_grid=y_grid;
            app.data.z_grid=z_grid;
            app.data.grid_points=grid_points;
            app.data.density=density;
            app.UITable_Resultados.Data=[]; % Vacío la tabla, porque he calculado densidades y no tengo máximos
            app.Find_Maxima.Enable="on";
            % guardo la información en el log
            a = app.ConsoleListBox.Items;
            DateString=char(string(datetime));
            S1 = [DateString, ' - I computed the density of the points in ',num2str(floor(toc)),' s. Now you can extract the peaks.'];
            S=[a S1];
            app.ConsoleListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.ConsoleListBox, 'bottom')
            app.data.log = S;


        end

        % Button pushed function: Find_Maxima
        function Find_MaximaPushed(app, event)
            [relative_maxima, top_N_density, top_N_positions, top_N_normals ] = f_busca_max_relativo_densidad(app,app.data.x_grid, app.data.y_grid, app.data.z_grid, app.data.density, app.MindensityEditField.Value, 2^app.NsecEditField.Value, app.MaxangleEditField.Value, app.NofppalEditField.Value);
            app.data.relative_maxima=relative_maxima;
            app.data.top_N_density=top_N_density;
            app.data.top_N_positions=top_N_positions;
            app.data.top_N_normals=top_N_normals;
            % Inicializar la matriz de resultados
            resultados = zeros(length(top_N_density), 6);
            % Llenar la matriz de resultados
            for i = 1:length(top_N_density)
                % Calcular la dirección de buzamiento y el buzamiento
                [dip_direction, dip] = f_vnorm2clar_v02(app,top_N_positions(i, :));
                % Llenar la matriz de resultados
                resultados(i, :) = [dip_direction, dip, top_N_density(i), top_N_positions(i, 1), top_N_positions(i, 2), top_N_positions(i, 3)];
            end
            % Crear una matriz de celdas para la tabla, incluyendo el número de fila
            num_filas = size(resultados, 1);
            tabla_datos = cell(num_filas, 7); % 7 columnas: Número de fila, Dip Direction, Dip, Density, nx, ny, nz
            for i = 1:num_filas
                tabla_datos{i, 1} = i; % Número de fila
                tabla_datos{i, 2} = resultados(i, 1); % Dip Direction
                tabla_datos{i, 3} = resultados(i, 2); % Dip
                tabla_datos{i, 4} = resultados(i, 3); % Density
                tabla_datos{i, 5} = resultados(i, 4); % nx
                tabla_datos{i, 6} = resultados(i, 5); % ny
                tabla_datos{i, 7} = resultados(i, 6); % nz
            end
            % Actualizar la UITable con los datos
            app.UITable_Resultados.Data = tabla_datos; app.UITable_Resultados.Enable="on";
            app.data.resultados=resultados;
            % Activo los botones para dibujar las densidades
            app.LabelsCheckBox.Enable="on";
            app.PeaksCheckBox.Enable="on";
            app.Plot_Contours.Enable="on";
            app.ColormapDropDown.Enable="on"; 
            app.inversecolormapCheckBox.Enable="on"; 
            app.TransparencyEditField.Enable="on";
            app.FillcontourplotCheckBox.Enable="on";
            app.EditPanel.Enable="on";
            app.Button_bajatop.Enable="on";
            app.Button_bajafila.Enable="on";
            app.Button_subefila.Enable="on";
            app.Button_subetop.Enable="on";
            app.Button_deleterow.Enable="on";
            app.Button_addrow.Enable="on";
            % guardo la información en el log
            a = app.ConsoleListBox.Items;
            DateString=char(string(datetime));
            S1 = [DateString, ' - I found ',num2str(num_filas), ' peaks.'];
            S2 = [DateString, ' - Please see the table of results.'];
            S3 = [DateString, ' - Now, you can plot the sphere.'];
            S=[a S1 S2 S3];
            app.ConsoleListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.ConsoleListBox, 'bottom')
            app.data.log = S;
        end

        % Button pushed function: Plot_Contours
        function Plot_ContoursPushed(app, event)
            % Crear una malla de puntos en la superficie de la esfera
            tic;
            resolucion=2^app.NsecEditField.Value;
            density=app.data.density;
            top_N_positions=app.data.top_N_positions;
            top_N_density=app.data.top_N_density;
            transparency=app.TransparencyEditField.Value;
            offset = 0.1; % Separación desde la superficie para las etiquetas
            alpha = 15; % Hasta donde llegan las líneas desde los polos
            [theta_grid, phi_grid] = meshgrid(linspace(0, 2*pi, resolucion), linspace(0, pi, resolucion));
            x_grid = sin(phi_grid) .* cos(theta_grid);
            y_grid = sin(phi_grid) .* sin(theta_grid);
            z_grid = cos(phi_grid);
            % Dibujo el contorno que me piden
            switch app.FillcontourplotCheckBox.Value
                case 1
                    % Dibujo la densidad con colores, sin contornos
                    % Dibujo la esfera y densidad
                    [h] = f_dibuja_densidad(app,x_grid, y_grid, z_grid, density, alpha, offset, transparency);
                    % Pongo el mapa de colores
                    switch app.ColormapDropDown.Value
                        case 'batlowW'
                            cmap = f_colormaps(app,'batlowW');
                        otherwise
                            cmap=colormap(app.ColormapDropDown.Value);
                    end
                    switch app.inversecolormapCheckBox.Value
                        case 1
                            cmap=flipud(cmap);
                        otherwise
                            % no invierto y no hago nada
                    end
                    colormap(cmap);
                    % guardo la información en el log
                    a = app.ConsoleListBox.Items;
                    DateString=char(string(datetime));
                    S1 = [DateString, ' - Filled contours plotted in ',num2str(floor(toc)),' s.'];
                    S=[a S1];
                    app.ConsoleListBox.Items=S;
                    % fijo la vista del listbox en la última entrada
                    scroll(app.ConsoleListBox, 'bottom')
                    app.data.log = S;
                case 0
                    % No dibujo relleno sino sólo contornos

                    % Dibujo los contornos
                    f_dibuja_contorno(app,theta_grid, phi_grid, density,  alpha, offset, transparency);
                    % Dibujar puntos rojos en los máximos relativos encontrados
                    % hold on;
                    % plot3(top_N_positions(:,1), top_N_positions(:,2), top_N_positions(:,3), 'ko', 'MarkerSize', 8, 'MarkerFaceColor', 'r');
                    % plot3(-top_N_positions(:,1), -top_N_positions(:,2), -top_N_positions(:,3), 'ksquare', 'MarkerSize', 8, 'MarkerFaceColor', 'b');
                    % % Añadir etiquetas en los máximos relativos encontrados
                    % for i = 1:length(top_N_density)
                    %     label_position = top_N_positions(i, :) * (1 + offset);
                    %     text(label_position(1), label_position(2), label_position(3), ...
                    %         ['J_{', num2str(i),'}'], 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'BackgroundColor', 'w', 'EdgeColor', 'k');
                    %     inverted_position = -top_N_positions(i, :) * (1 + offset);
                    %     text(inverted_position(1), inverted_position(2), inverted_position(3), ...
                    %         ['J_{', num2str(i), '}*'], 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'BackgroundColor', 'w', 'EdgeColor', 'k');
                    % end
                    % hold off;
                    % guardo la información en el log
                    a = app.ConsoleListBox.Items;
                    DateString=char(string(datetime));
                    S1 = [DateString, ' - Contours plotted in ',num2str(floor(toc)),' s.'];
                    S=[a S1];
                    app.ConsoleListBox.Items=S;
                    % fijo la vista del listbox en la última entrada
                    scroll(app.ConsoleListBox, 'bottom')
                    app.data.log = S;
                otherwise
                    % Algo ha fallado
            end
            % Datos de la tabla
            hold on; % Dejo la esfera para seguir dibujando cosas
            tabla = app.UITable_Resultados.Data;
            % Offset para las etiquetas
            offset = 0.1;
            % Inicializar matrices para las posiciones
            posiciones = [];
            posiciones_invertidas = [];
            % Pongo las etiquetas, si se ha pedido claro está
            switch app.LabelsCheckBox.Value
                case 1
                    % Añadir etiquetas en los máximos relativos encontrados
                    for i = 1:size(tabla, 1)
                        indicador = cell2mat(tabla(i, 1));
                        nx = cell2mat(tabla(i, 5));
                        ny = cell2mat(tabla(i, 6));
                        nz = cell2mat(tabla(i, 7));

                        % Calcular la posición de la etiqueta con offset
                        label_position = [nx, ny, nz] * (1 + offset);
                        text(label_position(1), label_position(2), label_position(3), ...
                            ['J_{', num2str(indicador), '}'], 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'BackgroundColor', 'w', 'EdgeColor', 'k');

                        % Calcular la posición invertida de la etiqueta con offset
                        inverted_position = -[nx, ny, nz] * (1 + offset);
                        text(inverted_position(1), inverted_position(2), inverted_position(3), ...
                            ['J_{', num2str(indicador), '}*'], 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'BackgroundColor', 'w', 'EdgeColor', 'k');

                        % Guardar las posiciones originales sin offset
                        posiciones = [posiciones; [nx, ny, nz]];
                        posiciones_invertidas = [posiciones_invertidas; -[nx, ny, nz]];
                    end
                case 0
                    % Calcular posiciones sin añadir etiquetas
                    for i = 1:size(tabla, 1)
                        nx = cell2mat(tabla(i, 5));
                        ny = cell2mat(tabla(i, 6));
                        nz = cell2mat(tabla(i, 7));
                        % Guardar las posiciones originales sin offset
                        posiciones = [posiciones; [nx, ny, nz]];
                        posiciones_invertidas = [posiciones_invertidas; -[nx, ny, nz]];
                    end
            end
            % Dibujar puntos rojos en los máximos relativos encontrados, si se pide, que normalmente sí.
            switch app.PeaksCheckBox.Value
                case 1
                    % Dibujo los picos
                    plot3(posiciones(:,1), posiciones(:,2), posiciones(:,3), 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r');
                    plot3(posiciones_invertidas(:,1), posiciones_invertidas(:,2), posiciones_invertidas(:,3), 'ksquare', 'MarkerSize', 8, 'MarkerFaceColor', 'b');
                case 0
                    % Pues no los dibujo
                otherwise
                    % Algo ha fallado
            end
            hold off;


        end

        % Button pushed function: Plot_Normals
        function Plot_NormalsPushed(app, event)
            % resolucion=2^app.NsecEditField.Value-1;
            resolucion=2^app.NsecEditField.Value;
            transparency=app.TransparencyEditField.Value;
            offset = 0.1; % Separación desde la superficie para las etiquetas
            alpha = 15; % Hasta donde llegan las líneas desde los polos
            [theta_grid, phi_grid] = meshgrid(linspace(0, 2*pi, resolucion), linspace(0, pi, resolucion));
            x_grid = sin(phi_grid) .* cos(theta_grid);
            y_grid = sin(phi_grid) .* sin(theta_grid);
            z_grid = cos(phi_grid);
            [h] = f_dibuja_normales(app,x_grid, y_grid, z_grid, app.data.datos, alpha, offset, transparency, resolucion);

        end

        % Value changed function: FillcontourplotCheckBox
        function FillcontourplotCheckBoxValueChanged(app, event)
            value = app.FillcontourplotCheckBox.Value;
            switch value
                case 1
                    % Tengo el relleno de contorno activado
                    app.ColormapDropDown.Enable="on";
                    app.inversecolormapCheckBox.Enable="on";
                    app.LabelsCheckBox.Enable="on";
                case 0
                    % Desactivo las opciones del fill contour
                    app.ColormapDropDown.Enable="off";
                    app.inversecolormapCheckBox.Enable="off";
            end
        end

        % Callback function: PushTool_OpenFile
        function PushTool_OpenFileClicked(app, event)
            f_openfile(app);
        end

        % Callback function: PushTool_About
        function PushTool_AboutClicked(app, event)
            about;
        end

        % Button pushed function: Button_addrow
        function Button_addrowPushed(app, event)
            selectedCell = app.UITable_Resultados.Selection;
            if isempty(selectedCell)
                return;
            end
            rowIndex = selectedCell(1);
            tabla = app.UITable_Resultados.Data;
            newRow = zeros(1, size(tabla, 2));

            % Convertir newRow al mismo tipo de datos que tabla
            if iscell(tabla)
                newRow = num2cell(newRow);
            end

            app.UITable_Resultados.Data = [tabla(1:rowIndex-1, :); newRow; tabla(rowIndex:end, :)];
            app.UITable_Resultados.Selection = [rowIndex, selectedCell(2)];
        end

        % Button pushed function: Button_deleterow
        function Button_deleterowPushed(app, event)
            selectedCell = app.UITable_Resultados.Selection;
            if isempty(selectedCell)
                return;
            end
            rowIndex = selectedCell(1);
            tabla = app.UITable_Resultados.Data;
            if size(tabla, 1) > 1
                app.UITable_Resultados.Data = [tabla(1:rowIndex-1, :); tabla(rowIndex+1:end, :)];
            end
        end

        % Button pushed function: Button_subefila
        function Button_subefilaPushed(app, event)
            selectedCell = app.UITable_Resultados.Selection;
            if isempty(selectedCell)
                return;
            end
            rowIndex = selectedCell(1);
            if rowIndex > 1
                tabla = app.UITable_Resultados.Data;
                tabla([rowIndex-1, rowIndex], :) = tabla([rowIndex, rowIndex-1], :);
                app.UITable_Resultados.Data = tabla;
                app.UITable_Resultados.Selection = [rowIndex-1, selectedCell(2)];
            end
        end

        % Button pushed function: Button_bajafila
        function Button_bajafilaPushed(app, event)
            selectedCell = app.UITable_Resultados.Selection;
            if isempty(selectedCell)
                return;
            end
            rowIndex = selectedCell(1);
            tabla = app.UITable_Resultados.Data;
            if rowIndex < size(tabla, 1)
                tabla([rowIndex, rowIndex+1], :) = tabla([rowIndex+1, rowIndex], :);
                app.UITable_Resultados.Data = tabla;
                app.UITable_Resultados.Selection = [rowIndex+1, selectedCell(2)];
            end
        end

        % Button pushed function: Button_subetop
        function Button_subetopPushed(app, event)
            selectedCell = app.UITable_Resultados.Selection;
            if isempty(selectedCell)
                return;
            end
            rowIndex = selectedCell(1);
            if rowIndex > 1
                tabla = app.UITable_Resultados.Data;
                row = tabla(rowIndex, :);
                tabla(rowIndex, :) = [];
                app.UITable_Resultados.Data = [row; tabla];
                app.UITable_Resultados.Selection = [1, selectedCell(2)];
            end
        end

        % Button pushed function: Button_bajatop
        function Button_bajatopPushed(app, event)
            selectedCell = app.UITable_Resultados.Selection;
            if isempty(selectedCell)
                return;
            end
            rowIndex = selectedCell(1);
            tabla = app.UITable_Resultados.Data;
            if rowIndex < size(tabla, 1)
                row = tabla(rowIndex, :);
                tabla(rowIndex, :) = [];
                app.UITable_Resultados.Data = [tabla; row];
                app.UITable_Resultados.Selection = [size(tabla, 1), selectedCell(2)];
            end
        end

        % Cell edit callback: UITable_Resultados
        function UITable_ResultadosCellEdit(app, event)
   % Get the edited cell indices
    indices = event.Indices;
    row = indices(1);
    col = indices(2);
    
    % Check if the edited cell is in the dipdir or dip column
    if col == 2 || col == 3
        % Get the updated dipdir and dip values
        dipdir = app.UITable_Resultados.Data{row, 2};
        dip = app.UITable_Resultados.Data{row, 3};
        
        % Convert degrees to radians
        dipdir_rad = deg2rad(dipdir);
        dip_rad = deg2rad(dip);
        
        % Calculate nx, ny, nz
        nx = -sin(dip_rad) * sin(dipdir_rad);
        ny = -sin(dip_rad) * cos(dipdir_rad);
        nz = -cos(dip_rad);
        
        % Ensure nz is positive
        if nz < 0
            nx = -nx;
            ny = -ny;
            nz = -nz;
        end
        
        % Update the table with the new nx, ny, nz values
        app.UITable_Resultados.Data{row, 5} = nx;
        app.UITable_Resultados.Data{row, 6} = ny;
        app.UITable_Resultados.Data{row, 7} = nz;
        
        % Convert cartesian coordinates to spherical coordinates
        [theta, phi, ~] = cart2sph(nx, ny, nz);
        
        % Interpolate the density using the theta_grid, phi_grid, and density data from app.data
        density = interp2(app.data.theta_grid, app.data.phi_grid, app.data.density, theta, phi, 'spline');
        
        % Update the table with the estimated density value
        app.UITable_Resultados.Data{row, 4} = density;
    end
        end

        % Callback function: PushTool_SaveState
        function PushTool_SaveStateClicked(app, event)
            if isprop(app, 'lastFilePath') && ~isempty(app.lastFilePath)
                defaultPath = app.lastFilePath;
            else
                defaultPath = pwd; % Carpeta actual si no existe lastFilePath
            end

            if isprop(app, 'data') && isfield(app.data, 'file')
                [~, defaultFile, ~] = fileparts(app.data.file); % Eliminar la extensión
                defaultFile = [defaultFile, '.mat']; % Agregar la extensión .mat
            else
                defaultFile = 'appState.mat';
            end

            [file, path] = uiputfile('*.mat', 'Guardar estado de la app', fullfile(defaultPath, defaultFile));
            if isequal(file, 0)
                disp('El usuario canceló la operación');
                return;
            end

            app.lastFilePath = path; % Guardar la última ruta utilizada
            filename = fullfile(path, file);

            state = struct();
            props = properties(app);
            for i = 1:length(props)
                name = props{i};
                comp = app.(name);  % Puede ser un componente o una propiedad
                c = string(class(comp));
                if isprop(comp, 'Value')
                    state.(name).Value = comp.Value;
                end
                if isprop(comp, 'Enable')
                    state.(name).Enable = comp.Enable;
                end
                if isprop(comp, 'Visible')
                    state.(name).Visible = comp.Visible;
                end
                if isprop(comp, 'Data')
                    state.(name).Data = comp.Data;
                end
                if c == "matlab.ui.control.DropDown"
                    % Manejo especial para DropDown
                    v = comp.Value;
                    if ~isempty(v)
                        id = find(strcmp(comp.Items, v));
                        state.(name).Value = comp.Items{id};
                    end
                elseif c == "matlab.ui.container.CheckBoxTree"
                    tags = arrayfun(@(x) string(x.Tag), comp.CheckedNodes, 'UniformOutput', false);
                    state.(name).CheckedNodes = tags;
                elseif c == "matlab.ui.control.ListBox"
                    state.(name).Items = comp.Items;
                end
            end

            % Guardar los datos adicionales en app.data
            if isprop(app, 'data')
                state.data = app.data;
            end

            save(filename, 'state');

            % Traer la GUI al frente
            figure(app.SphericalDensityToolUIFigure);
        end

        % Callback function: PushTool_LoadState
        function PushTool_LoadStateClicked(app, event)
            if isprop(app, 'lastFilePath') && ~isempty(app.lastFilePath)
                defaultPath = app.lastFilePath;
            else
                defaultPath = pwd; % Carpeta actual si no existe lastFilePath
            end

            [file, path] = uigetfile('*.mat', 'Cargar estado de la app', defaultPath);
            if isequal(file, 0)
                disp('El usuario canceló la operación');
                return;
            end

            app.lastFilePath = path; % Guardar la última ruta utilizada
            filename = fullfile(path, file);

            if exist(filename, 'file')
                load(filename, 'state');
                fields = fieldnames(state);
                for i = 1:length(fields)
                    name = fields{i};
                    try
                        if strcmp(name, 'data')
                            app.data = state.data; % Restaurar los datos adicionales
                        else
                            comp = app.(name);
                            if isfield(state.(name), 'Value') && isprop(comp, 'Value')
                                comp.Value = state.(name).Value;
                            end
                            if isfield(state.(name), 'Enable') && isprop(comp, 'Enable')
                                comp.Enable = state.(name).Enable;
                            end
                            if isfield(state.(name), 'Visible') && isprop(comp, 'Visible')
                                comp.Visible = state.(name).Visible;
                            end
                            if isfield(state.(name), 'Data') && isprop(comp, 'Data')
                                comp.Data = state.(name).Data;
                            end
                            if class(comp) == "matlab.ui.container.CheckBoxTree" && isfield(state.(name), 'CheckedNodes')
                                tags = state.(name).CheckedNodes;
                                checkedNodes = arrayfun(@(x) findall(0, 'Tag', x), tags, 'UniformOutput', false);
                                comp.CheckedNodes = [checkedNodes{:}];
                            elseif class(comp) == "matlab.ui.control.ListBox" && isfield(state.(name), 'Items')
                                comp.Items = state.(name).Items;
                            end
                        end
                    catch err
                        disp(['No se pudo cargar el campo guardado ', name, ': ', err.identifier]);
                    end
                end
            else
                disp('Archivo de configuración no encontrado');
            end

            % Traer la GUI al frente
            figure(app.SphericalDensityToolUIFigure);
        end

        % Value changed function: LabelsCheckBox
        function LabelsCheckBoxValueChanged(app, event)
            value = app.LabelsCheckBox.Value;
            switch value
                case 1
                    % Al tener etiquetas activadas, activo dibujar el punto
                    app.PeaksCheckBox.Value=1;
                otherwise
                    % No hago nada, lo dejo como está
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SphericalDensityToolUIFigure = uifigure('Visible', 'off');
            app.SphericalDensityToolUIFigure.Name = 'Spherical Density Tool';
            app.SphericalDensityToolUIFigure.Icon = fullfile(pathToMLAPP, 'image_contours.png');
            app.Toolbar = uitoolbar(app.SphericalDensityToolUIFigure);
            app.PushTool_OpenFile = uipushtool(app.Toolbar);
            app.PushTool_OpenFile.ClickedCallback = createCallbackFcn(app, @PushTool_OpenFileClicked, true);
            app.PushTool_OpenFile.Icon = fullfile(pathToMLAPP, 'icon_fa-file.png');
            app.PushTool_SaveState = uipushtool(app.Toolbar);
            app.PushTool_SaveState.ClickedCallback = createCallbackFcn(app, @PushTool_SaveStateClicked, true);
            app.PushTool_SaveState.Icon = fullfile(pathToMLAPP, 'fa-floppy-o.png');
            app.PushTool_LoadState = uipushtool(app.Toolbar);
            app.PushTool_LoadState.ClickedCallback = createCallbackFcn(app, @PushTool_LoadStateClicked, true);
            app.PushTool_LoadState.Icon = fullfile(pathToMLAPP, 'fa-folder-open.png');
            app.PushTool_About = uipushtool(app.Toolbar);
            app.PushTool_About.ClickedCallback = createCallbackFcn(app, @PushTool_AboutClicked, true);
            app.PushTool_About.Icon = fullfile(pathToMLAPP, 'icon_about.png');
            app.UITable_Resultados = uitable(app.SphericalDensityToolUIFigure);
            app.UITable_Resultados.ColumnName = {'Id'; 'Dip_dir º'; 'Dip º'; 'Density'; 'Nx'; 'Ny'; 'Nz'};
            app.UITable_Resultados.RowName = {};
            app.UITable_Resultados.ColumnEditable = [true true true false false false false];
            app.UITable_Resultados.CellEditCallback = createCallbackFcn(app, @UITable_ResultadosCellEdit, true);
            app.UITable_Resultados.Enable = 'off';
            app.EditPanel = uipanel(app.SphericalDensityToolUIFigure);
            app.EditPanel.Title = 'Edit';
            app.Button_addrow = uibutton(app.EditPanel, 'push');
            app.Button_addrow.ButtonPushedFcn = createCallbackFcn(app, @Button_addrowPushed, true);
            app.Button_addrow.Icon = fullfile(pathToMLAPP, 'fa-calendar-plus-o.png');
            app.Button_addrow.Enable = 'off';
            app.Button_addrow.Tooltip = {'Move to top'};
            app.Button_addrow.Text = '';
            app.Button_deleterow = uibutton(app.EditPanel, 'push');
            app.Button_deleterow.ButtonPushedFcn = createCallbackFcn(app, @Button_deleterowPushed, true);
            app.Button_deleterow.Icon = fullfile(pathToMLAPP, 'fa-ban.png');
            app.Button_deleterow.Enable = 'off';
            app.Button_deleterow.Tooltip = {'Move to top'};
            app.Button_deleterow.Text = '';
            app.Button_subetop = uibutton(app.EditPanel, 'push');
            app.Button_subetop.ButtonPushedFcn = createCallbackFcn(app, @Button_subetopPushed, true);
            app.Button_subetop.Icon = fullfile(pathToMLAPP, 'fa-angle-double-up.png');
            app.Button_subetop.Enable = 'off';
            app.Button_subetop.Tooltip = {'Move to top'};
            app.Button_subetop.Text = '';
            app.Button_subefila = uibutton(app.EditPanel, 'push');
            app.Button_subefila.ButtonPushedFcn = createCallbackFcn(app, @Button_subefilaPushed, true);
            app.Button_subefila.Icon = fullfile(pathToMLAPP, 'fa-angle-up.png');
            app.Button_subefila.Enable = 'off';
            app.Button_subefila.Tooltip = {'Move to top'};
            app.Button_subefila.Text = '';
            app.Button_bajafila = uibutton(app.EditPanel, 'push');
            app.Button_bajafila.ButtonPushedFcn = createCallbackFcn(app, @Button_bajafilaPushed, true);
            app.Button_bajafila.Icon = fullfile(pathToMLAPP, 'fa-angle-down.png');
            app.Button_bajafila.Enable = 'off';
            app.Button_bajafila.Tooltip = {'Move to top'};
            app.Button_bajafila.Text = '';
            app.Button_bajatop = uibutton(app.EditPanel, 'push');
            app.Button_bajatop.ButtonPushedFcn = createCallbackFcn(app, @Button_bajatopPushed, true);
            app.Button_bajatop.Icon = fullfile(pathToMLAPP, 'fa-angle-double-down.png');
            app.Button_bajatop.Enable = 'off';
            app.Button_bajatop.Tooltip = {'Move to top'};
            app.Button_bajatop.Text = '';
            app.ControlPanel = uipanel(app.SphericalDensityToolUIFigure);
            app.ControlPanel.Tooltip = {'Draws the normals as their intersection with a sphere of R=1'};
            app.ControlPanel.Title = 'Control Panel';
            app.Plot_Contours = uibutton(app.ControlPanel, 'push');
            app.Plot_Contours.ButtonPushedFcn = createCallbackFcn(app, @Plot_ContoursPushed, true);
            app.Plot_Contours.Icon = fullfile(pathToMLAPP, 'image_contours.png');
            app.Plot_Contours.Enable = 'off';
            app.Plot_Contours.Tooltip = {'Shows the contour plot of density on the sphere and the maxima. Options: colourmap, filled, transparency.'};
            app.Plot_Contours.Text = '';
            app.Find_Maxima = uibutton(app.ControlPanel, 'push');
            app.Find_Maxima.ButtonPushedFcn = createCallbackFcn(app, @Find_MaximaPushed, true);
            app.Find_Maxima.Icon = fullfile(pathToMLAPP, 'image_fa-area-chart.png');
            app.Find_Maxima.Enable = 'off';
            app.Find_Maxima.Tooltip = {'Find the relative density maxima. The parameters are ''Min density'', ''N of ppal'', and ''Max angle''.'};
            app.Find_Maxima.Text = '';
            app.Compute_Density = uibutton(app.ControlPanel, 'push');
            app.Compute_Density.ButtonPushedFcn = createCallbackFcn(app, @Compute_DensityPushed, true);
            app.Compute_Density.Icon = fullfile(pathToMLAPP, 'image_fa-bar-chart.png');
            app.Compute_Density.Enable = 'off';
            app.Compute_Density.Tooltip = {'Calculates the density (Kernel Denstiy Estimator) of normals on the surface of the sphere in spherical coordinates. The inputs are: "resolution" (Nsec), and the bandwidth (a real positive number that defines smoothness of the density plot)'};
            app.Compute_Density.Text = '';
            app.Plot_Normals = uibutton(app.ControlPanel, 'push');
            app.Plot_Normals.ButtonPushedFcn = createCallbackFcn(app, @Plot_NormalsPushed, true);
            app.Plot_Normals.Icon = fullfile(pathToMLAPP, 'image_normals.png');
            app.Plot_Normals.Enable = 'off';
            app.Plot_Normals.Text = '';
            app.ConsoleListBox = uilistbox(app.SphericalDensityToolUIFigure);
            app.ConsoleListBox.Items = {'Wellcome to Spherical Density Tool'};
            app.ConsoleListBox.Value = 'Wellcome to Spherical Density Tool';
            app.DensityandmaximasettingsPanel = uipanel(app.SphericalDensityToolUIFigure);
            app.DensityandmaximasettingsPanel.Title = 'Density and maxima settings';
            app.NsecEditFieldLabel = uilabel(app.DensityandmaximasettingsPanel);
            app.NsecEditFieldLabel.Enable = 'off';
            app.NsecEditFieldLabel.Text = 'Nsec';
            app.NsecEditField = uieditfield(app.DensityandmaximasettingsPanel, 'numeric');
            app.NsecEditField.Limits = [2 Inf];
            app.NsecEditField.ValueDisplayFormat = '%.0f';
            app.NsecEditField.Enable = 'off';
            app.NsecEditField.Tooltip = {'Defines the grid resolution: 2^nsec-1. It must be a positive integer.'};
            app.NsecEditField.Value = 6;
            app.BandwidthLabel = uilabel(app.DensityandmaximasettingsPanel);
            app.BandwidthLabel.Enable = 'off';
            app.BandwidthLabel.Text = 'Bandwidth [º]';
            app.BandwidthEditField = uieditfield(app.DensityandmaximasettingsPanel, 'numeric');
            app.BandwidthEditField.Limits = [0 90];
            app.BandwidthEditField.Enable = 'off';
            app.BandwidthEditField.Tooltip = {'Defines the kernel bandwidth. This is a angle in degrees, so the bandwitch is sin(bd). Set zero value to set the optimal bandwidth.'};
            app.BandwidthEditField.Value = 2;
            app.MindensityLabel = uilabel(app.DensityandmaximasettingsPanel);
            app.MindensityLabel.Enable = 'off';
            app.MindensityLabel.Text = 'Min density ';
            app.MindensityEditField = uieditfield(app.DensityandmaximasettingsPanel, 'numeric');
            app.MindensityEditField.Limits = [1e-10 1];
            app.MindensityEditField.Enable = 'off';
            app.MindensityEditField.Tooltip = {'Minimum value of density to be a principal orientation candidate. It is the % of the maximun density value.'};
            app.MindensityEditField.Value = 0.01;
            app.NofppalEditFieldLabel = uilabel(app.DensityandmaximasettingsPanel);
            app.NofppalEditFieldLabel.Enable = 'off';
            app.NofppalEditFieldLabel.Text = 'N of ppal';
            app.NofppalEditField = uieditfield(app.DensityandmaximasettingsPanel, 'numeric');
            app.NofppalEditField.Limits = [1 100];
            app.NofppalEditField.ValueDisplayFormat = '%.0f';
            app.NofppalEditField.Enable = 'off';
            app.NofppalEditField.Tooltip = {'Maximum principal orientations to be extracted.'};
            app.NofppalEditField.Value = 10;
            app.MaxangleEditFieldLabel = uilabel(app.DensityandmaximasettingsPanel);
            app.MaxangleEditFieldLabel.Enable = 'off';
            app.MaxangleEditFieldLabel.Text = 'Max angle [º]';
            app.MaxangleEditField = uieditfield(app.DensityandmaximasettingsPanel, 'numeric');
            app.MaxangleEditField.Limits = [0 90];
            app.MaxangleEditField.Enable = 'off';
            app.MaxangleEditField.Tooltip = {'Maximum angle between normals to be considered as a principal orientation. In degrees.'};
            app.MaxangleEditField.Value = 30;
            app.ConsoleListBoxLabel = uilabel(app.SphericalDensityToolUIFigure);
            app.ConsoleListBoxLabel.Text = 'Console';
            app.PlotsettingsPanel = uipanel(app.SphericalDensityToolUIFigure);
            app.PlotsettingsPanel.Title = 'Plot settings';
            app.ColormapDropDownLabel = uilabel(app.PlotsettingsPanel);
            app.ColormapDropDownLabel.Enable = 'off';
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.PlotsettingsPanel);
            app.ColormapDropDown.Items = {'batlowW', 'jet', 'parula', 'hot', 'autumn', 'winter'};
            app.ColormapDropDown.Enable = 'off';
            app.ColormapDropDown.Value = 'batlowW';
            app.inversecolormapCheckBox = uicheckbox(app.PlotsettingsPanel);
            app.inversecolormapCheckBox.Enable = 'off';
            app.inversecolormapCheckBox.Text = 'inverse colormap';
            app.inversecolormapCheckBox.Value = true;
            app.TransparencyEditFieldLabel = uilabel(app.PlotsettingsPanel);
            app.TransparencyEditFieldLabel.Enable = 'off';
            app.TransparencyEditFieldLabel.Text = 'Transparency';
            app.TransparencyEditField = uieditfield(app.PlotsettingsPanel, 'numeric');
            app.TransparencyEditField.Limits = [0 1];
            app.TransparencyEditField.ValueDisplayFormat = '%.2f';
            app.TransparencyEditField.Enable = 'off';
            app.TransparencyEditField.Value = 0.85;
            app.FillcontourplotCheckBox = uicheckbox(app.PlotsettingsPanel);
            app.FillcontourplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @FillcontourplotCheckBoxValueChanged, true);
            app.FillcontourplotCheckBox.Enable = 'off';
            app.FillcontourplotCheckBox.Text = 'Fill contour plot';
            app.FillcontourplotCheckBox.Value = true;
            app.LabelsCheckBox = uicheckbox(app.PlotsettingsPanel);
            app.LabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @LabelsCheckBoxValueChanged, true);
            app.LabelsCheckBox.Enable = 'off';
            app.LabelsCheckBox.Text = 'Labels';
            app.PeaksCheckBox = uicheckbox(app.PlotsettingsPanel);
            app.PeaksCheckBox.Enable = 'off';
            app.PeaksCheckBox.Tooltip = {'Draw a point on every maximum.'};
            app.PeaksCheckBox.Text = 'Peaks';
            app.PeaksCheckBox.Value = true;
            % Show the figure after all components are created
            app.SphericalDensityToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SphericalDensityhTool_v00

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SphericalDensityToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SphericalDensityToolUIFigure)
        end
    end
end